classdef interface_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InterfaceREDv20UIFigure  matlab.ui.Figure
        InputMenu                matlab.ui.container.Menu
        ArduinoMenu              matlab.ui.container.Menu
        RaspberryMenu            matlab.ui.container.Menu
        NoneMenu                 matlab.ui.container.Menu
        ReadMeMenu               matlab.ui.container.Menu
        InstructionsMenu         matlab.ui.container.Menu
        Mapa2DPanel              matlab.ui.container.Panel
        Lamp                     matlab.ui.control.Lamp
        Switch                   matlab.ui.control.ToggleSwitch
        Image                    matlab.ui.control.Image
        Interfacev20Label        matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
        UIAxes3                  matlab.ui.control.UIAxes
        ReadyLampLabel           matlab.ui.control.Label
        ReadyLamp                matlab.ui.control.Lamp
        UITable                  matlab.ui.control.Table
        COMDropDownLabel         matlab.ui.control.Label
        COMDropDown              matlab.ui.control.DropDown
        ImageArduino             matlab.ui.control.Image
        ImageRaspberry           matlab.ui.control.Image
        ImageTest                matlab.ui.control.Image
        LampArduino              matlab.ui.control.Lamp
        LampRaspberry            matlab.ui.control.Lamp
        LampTest                 matlab.ui.control.Lamp
        SaveImagesButton         matlab.ui.control.Button
        ResetButton              matlab.ui.control.Button
        BaudRateDropDownLabel    matlab.ui.control.Label
        BaudRateDropDown         matlab.ui.control.DropDown
        ReCenterMapButton        matlab.ui.control.Button
    end


    properties (Access = public)
       
        key='aceX aceY aceZ lati longi altiMed vel pitch roll yaw'

        gx = 0
        gg = 0
        initial_coordinates=[];
        coords=[38.736789 -9.137551 ];
        dist_met=[0 0];
        alt=0;
        t=1;
        go= false
        A=0
        euler=0
        acel_data=0
        h=7; %height of cylinder
        deg=pi/180;
        coneRadius=0.5;
        coneHeight=2;
        N=100;
        centerCord=[0 0 7] % height
        
        height_fins=3;
        length_fins=1.5;
        
        fin_1=[];
        fin_2=[];
        X=[];
        Y=[];
        Z=[];
        
        last_vel=[0,0];
        
        baudrate=9600;
        new_data=[];
        com_door='';
        
    
        data=struct('gps',{}, 'ace', {} , 'angles', {})
        
        flag=0;
        
        mode=1; %1 arduino, 2 raspberry, 3 test
        
        DialogApp
    end
    
    methods (Access = private)
        
        %--------------------------------------------------------
        %Description: acts like a main loop, updates the map and all other
        %UI's
        %Input: app -> global variables
        %Output: ----
        function display_map(app)
            
            %Closes the port if it is opened
            if ~isempty(instrfind)
             fclose(instrfind);
              delete(instrfind);
            end
            
            %Opens serial arduino
            if app.go==true 
                s=serialport(app.com_door, app.baudrate);
                disp(app.baudrate);
            end
            
            %Opens save file
            datafile=fopen('datafile.txt.', "a");
            
            
                while app.go== true
                    %Reads Serial Lines
                    %app.new_data=shield(app);
                    key_test=split(app.key);
                    while 1
                        app.new_data=readline(s);
                        
                        data_num=str2num(app.new_data);
                        if ~isnan(data_num)
                            if size(data_num,2) == size(key_test,1)
                                break;
                            end 
                        end 
                    end
                    
                    app.new_data=[data_num app.t];

                    
                    %Saves data
                    fprintf(datafile, '%.5f', app.new_data);
                    fprintf(datafile,'\n');
                    
                    assign_data(app);
                    
                    %disp(app.data(1).gps)
                    %Initializes the 2D map for the first time
                    if app.flag==0
                        initialize_map(app, app.data(1).gps, app.data(2).gps, 14)
                        app.flag=1;
                    end
                    
                    drawnow %to update button status
                    if app.go == true
                       %pause(1) %to simulate the rate of data received
                       %sizeA=size(app.A);
                       %if app.t <= sizeA(2)
                           %x=app.A(:,app.t);
                           
                           
                           %Update trajectory
                           hold(app.gx,"on")
                           %geoplot(app.gx,[app.initial_coordinates(1)+x(2) app.coords(1)], [app.initial_coordinates(2)+x(3) app.coords(2)], 'Color','b', 'LineWidth', 2);
                           %app.coords(1)= app.initial_coordinates(1)+ x(2);
                           %app.coords(2)= app.initial_coordinates(2)+ x(3); 
                           geoplot(app.gx,[app.data(1).gps app.coords(1)], [app.data(2).gps app.coords(2)], 'Color','b', 'LineWidth', 2);
                            
                           
                           %Plot new line of trajectory
                           hold(app.UIAxes,"on")
                           d_x = meters_from_coords(app,0,0, app.coords(2), app.data(2).gps); %lon
                           d_y = meters_from_coords(app,app.coords(1), app.data(1).gps,0 , 0);
                           
                           [d_x, d_y] = correct_dist(app,app.coords(1), app.data(1).gps,app.coords(2), app.data(2).gps, d_x,d_y);
                           app.dist_met=[d_x d_y] +app.dist_met;


                           plot3(app.UIAxes, app.dist_met(1),app.dist_met(2), app.data(3).gps, 'o' )
                            
                           %Saves old coords
                           app.coords(1)= app.data(1).gps;
                           app.coords(2)= app.data(2).gps;
                           
                           %Add new data to the table
                           oldData = get(app.UITable,'Data');
                           newRow={app.data(1).gps, app.data(2).gps, app.data(3).gps, app.t};
                           newData = [oldData; newRow];
                           set(app.UITable,'Data',newData);
                           
                                                                                  
                           rodar(app, app.data(1).angles, app.data(2).angles, app.data(3).angles);
%                            disp(app.data(1).angles)
%                            disp(app.data(2).angles)
%                            disp(app.data(3).angles)
                           
                           %Calculate new velocity
                           %acel_to_vel(app, app.acel_data(:, app.t));
                           %disp(app.last_vel); 
                           update_vel(app, app.t, app.data(4).ace);
                           
                           app.t=app.t+1; %disp(t);
                           

                       %end
                    end
                end
                fclose(datafile);
                %save_data(app);
            end 
        
            
        %--------------------------------------------------------
        %Description: Creates the initial rocket
        %Input: app -> all the global variables
        %Output: --- 
        function create_rocket(app)
            %Cylinder Definition
            [x,y,z]=cylinder(app.coneRadius);
            z = z*app.h;
            
                %Cone Definition
            [x_cone, y_cone, z_cone] = cylinder([0 app.coneRadius], app.N);
            z_cone=-z_cone;
            z_cone=z_cone*app.coneHeight;
            height = max(abs(z_cone), [], 'all');
            z_cone=z_cone+app.h+height;
            
            app.X=cat(2,x,x_cone);
            app.Y=cat(2,y,y_cone);
            app.Z=cat(2,z,z_cone);
           
            
                %Fins Definition
            app.fin_1=[0 -app.length_fins 0;
                   0 app.length_fins 0;
                   0 0 app.height_fins];
        
            app.fin_2=[app.length_fins 0 0;
                   -app.length_fins 0 0;
                   0 0 app.height_fins];
            
                %Plot cylinder and cone
            hold(app.UIAxes2,'on');
            xlim(app.UIAxes2, [-10 10]);
            ylim(app.UIAxes2, [-10 10]);
            zlim(app.UIAxes2, [-10 10]);
            surf(app.UIAxes2, app.X,app.Y,app.Z, 'FaceColor','red', 'EdgeColor','k');
            view(app.UIAxes2,45,45);   
            patch(app.UIAxes2,app.fin_1(:,1), app.fin_1(:,2), app.fin_1(:,3), "red");
            patch(app.UIAxes2,app.fin_2(:,1), app.fin_2(:,2), app.fin_2(:,3), "red");
            hold(app.UIAxes2,'off');
        end
        
        
        %-------------------------TEST MODE----------------------------
        %Description: reads trajectory file ("angles.txt")
        %Input: app -> global variables
        %Output: ----   
        function euler=read_file_euler(app)
            euler_angles = fopen('./data/angles.txt','r');
            formatSpec = 'p: %f, r: %f, y: %f \n';
            sizeA = [3 inf];
            [euler, count] = fscanf(euler_angles,formatSpec, sizeA);
            fclose(euler_angles);
            %disp(count);
        end
        
        
        %--------------------------TEST MODE--------------------------
        %Description: reads accelaration file ("acel.txt")
        %Input: app -> global variables
        %Output: ---- 
        function acel_data=read_file_acel(app)
            acel = fopen('./data/acel.txt','r');
            formatSpec= '%f, %f \n';
            sizeA=[2 inf];
            acel_data = fscanf(acel,formatSpec, sizeA);
            fclose(acel);
        end
        
        
         %-------------------------TEST MODE--------------------------
        %Description: reads trajectory file ("Example_trajectory.txt")
        %Input: app -> global variables
        %Output: ----
        function A=read_file_traj(app)
            trajectory = fopen('./exp.txt','r');
            formatSpec = '%f %f %f %f %f %f %f %f %f %f';
            sizeA = [10 Inf];
            A = fscanf(trajectory,formatSpec, sizeA);
            fclose(trajectory);
        end
        
        
        %--------------------------------------------------------
        %Description: calculates distance between two points given the
        %coordinates from those points
        %Input: lat1,lon1 -> latitude/longitude of the first point (deg) 
        %lat2,lon2 -> latitude/longitude of the second point (deg)
        %Output: d -> distance between the two points
        function d = meters_from_coords(~,lat1,lat2,lon1,lon2)
            r_earth=6371000.0 ;
         
            
            lat1_rad=lat1*pi/180;
            lat2_rad=lat2*pi/180;
            dif_lat=lat2_rad-lat1_rad;
            dif_lon=(lon2-lon1)*pi/180;
            
            a=sin(dif_lat/2)*sin(dif_lat/2) + cos(lat1_rad)*cos(lat2_rad)*sin(dif_lon/2)*sin(dif_lon/2);
            c= 2* atan2(sqrt(a), sqrt(1-a));
            

            d=r_earth*c; 
        end
        
        
        %-------------------------WIP--------------------------
        %Description: Converts the acceleration received to velocity
        %Input: app -> global variables, current_acel -> cuurent
        %accelaration (m/s^2)
        %Output: ----
        function acel_to_vel(app, current_acel)
            %disp(app.last_vel);
            %disp(current_acel);
            
                %Calculate new velocity
            app.last_vel(2)=app.last_vel(2)+current_acel(2)*(current_acel(1)-app.last_vel(1));
                
                %Set new time
            app.last_vel(1)=current_acel(1); 
        end
        
        
        %--------------------------------------------------------
        %Description:  Updates the velocity graph
        %Input: app -> global variables
        %Output: ----
        function update_vel(app, time, velocity)
            hold(app.UIAxes3,'on');
            plot(app.UIAxes3, time, velocity,'o');
            hold(app.UIAxes3,'off');
        end
        
        %--------------------------------------------------------
        %Description: Rotates a 3D model of a rocket (fins+body+nose)
        %according to the euler angles provided .
        %Input: global variables(app), roll (deg), pitch (deg), yaw (deg)
        %Output: ----
        function rodar(app, roll, pitch, yaw)
            eul=[(roll)*app.deg pitch*app.deg (yaw)*app.deg];   %convert the angles to radian
            rotm = eul2rotm(eul);       %creates rotation matrix
            
            %Creates a new matrix that will contain the coords of the body
            len=size(app.X);
            x_plot=zeros(len(1),len(2));
            y_plot=zeros(len(1),len(2));
            z_plot=zeros(len(1),len(2));
            
            %Calculates new coords
            for i=1:len(2)
                B=[app.X(1,i), app.Y(1,i), app.Z(1,i)]* rotm;
                C=[app.X(2,i), app.Y(2,i), app.Z(2,i)]* rotm;
                x_plot(1,i)=B(1);
                y_plot(1,i)=B(2);
                z_plot(1,i)=B(3);
                
                x_plot(2,i)=C(1);
                y_plot(2,i)=C(2);
                z_plot(2,i)=C(3);
            end
        
            %Fins new coords
            fin_1_plot=app.fin_1*rotm;
            fin_2_plot=app.fin_2*rotm;
            
            %Plot rocket
            cla(app.UIAxes2) %clears axis
            hold(app.UIAxes2,'on');
            xlim(app.UIAxes2, [-10 10]);
            ylim(app.UIAxes2, [-10 10]);
            zlim(app.UIAxes2, [-10 10]);
            surf(app.UIAxes2, x_plot,y_plot,z_plot,'edgecolor', 'k', 'FaceColor','red');
            p=patch(app.UIAxes2, fin_1_plot(:,1), fin_1_plot(:,2),fin_1_plot(:,3), "red");
            p=patch(app.UIAxes2, fin_2_plot(:,1),fin_2_plot(:,2), fin_2_plot(:,3), "red");
            
            view(app.UIAxes2,45,45);
            hold(app.UIAxes2,'off');
        end
        
        %--------------------------------------------------------
        %Description: assigns the data received from the arduino/raspberry
        %to a structure
        %Input: global variables, app
        %Output: ----
        function assign_data(app)
            %GPS Data
            app.data(1).gps=app.new_data(4);
            app.data(2).gps=app.new_data(5);
            %app.data(3).gps=app.new_data(6);
            app.data(3).gps=app.new_data(6);
            
            %Accelaration Data
            app.data(1).ace=app.new_data(1);
            app.data(2).ace=app.new_data(2);
            app.data(3).ace=app.new_data(3);
            app.data(4).ace=app.new_data(7); %velocity
            
            %Euler Angles Data
            app.data(1).angles=app.new_data(8); %pitch
            app.data(2).angles=app.new_data(9); %roll
            app.data(3).angles=app.new_data(10); %yaw
        end
        
        %--------------------------------------------------------
        %Description: Initializes the map 
        %Input: app -> global variables, lati -> initial latitude, longi ->
        %initial longitude; zoom -> initial zoom level
        %Output: ----
        function initialize_map(app, lati, longi, zoom)
            app.gx.MapCenter = [lati, longi];
            app.gx.ZoomLevel = zoom;
            app.coords= [lati,longi];
        end
    
        
        %--------------------------------------------------------
        %Description: Saves the final graphs
        %Input: app -> global variables
        %Output: ----
        function save_data(app)
            app.ReadyLamp.Color = 'blue';
            date_string = datestr(now,'dd_mm_yy-HH_MM_SS');
            mkdir (date_string)
            traje=['./' date_string '/Trajectory.png'];
            mapa=['./' date_string '/MapaFinal.png'];
            vel=['./' date_string '/Velocity.png'];
            exportgraphics(app.UIAxes, traje);
            exportgraphics(app.Mapa2DPanel, mapa);
            exportgraphics(app.UIAxes3, vel);
            app.ReadyLamp.Color = 'green';
        end
    
        
        %--------------------------------------------------------
        %Description: Corrects the distance signals so to correctly display
        %the trajectory
        %Input: app -> global variables, d_x -> distance in the x axis, d_y
        %-> distance in the y axis
        %Output: d_x, d_y -> corrected distances
        function [d_x , d_y]= correct_dist(~,lat1,lat2,lon1,lon2,d_x,d_y)
            %Just to make sure the distances are positive
            d_y=abs(d_y);
            d_x=abs(d_x);
            
            %Correct dist signal
            if lat1 > lat2
                d_y=-d_y;
            end
            
            if lon1 > lon2
                d_x=-d_x;
            end
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Create Table
            app.UITable.Data = {0, 0, app.alt, app.t};
            
            %Create Maps
            app.gx=geoaxes(app.Mapa2DPanel,"Basemap","satellite"); 
            app.gx.InnerPosition = app.gx.OuterPosition;
            
            %Creates Trajectory graph
            plot3(app.UIAxes, 0, 0, 0)
            zlim(app.UIAxes, [0 100]);
            
            %Creating Rocket
            create_rocket(app);
            
            %%------ONLY USED IN THE TESTING PHASE WITHOUT
            %%ARDUINO/RASPBERRY READING ----%%%%
            %Read Trajectory, euler angles and acel
            %app.A=read_file_traj(app);
            %app.euler=read_file_euler(app);
            %app.acel_data=read_file_acel(app);
            app.ReadyLamp.Color = 'green';
            %disp(app.acel_data(2,:)); 
            
            
            %Start save file
            datafile=fopen('datafile.txt.', "w");
            fprintf(datafile, app.key);
            fclose(datafile);
            
            %Checks deafult mode (Arduino/Raspberry/None)
            switch app.mode
                case 1 %Arduino On
                    app.LampArduino.Enable = true;
                    app.ArduinoMenu.Checked = true;  
                case 2 %Raspberry On
                    app.LampRaspberry.Enable = true;
                    app.RaspberryMenu.Checked = true; 
                case 3 %Test mode on (reads data from files)
                    app.LampTest.Enable = true; 
                    app.NoneMenu.Checked = true;
            end

        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            switch value
                case 'On'
                    app.Lamp.Color = 'green';
                    app.go= true;
                    display_map(app)
                case 'Off'
                    app.Lamp.Color  = 'red';
                    app.go= false;
                    display_map(app)
            end
        end

        % Value changed function: COMDropDown
        function COMDropDownValueChanged(app, event)
            value = app.COMDropDown.Value;
            app.com_door=strcat('COM',value);
        end

        % Menu selected function: ArduinoMenu
        function ArduinoMenuSelected(app, event)
            if app.LampArduino.Enable == true
                app.LampArduino.Enable = false;
                app.ArduinoMenu.Checked = 0 ;
            else
                app.LampArduino.Enable = true;
                app.ArduinoMenu.Checked = 1 ;
                app.mode = 1 ;
                app.RaspberryMenu.Checked = 0 ;
                app.LampRaspberry.Enable = 0 ; 
                app.NoneMenu.Checked = 0 ;
                app.LampTest.Enable = 0 ; 
            end
        end

        % Menu selected function: RaspberryMenu
        function RaspberryMenuSelected(app, event)
            if app.LampRaspberry.Enable == true
                app.LampRaspberry.Enable = false;
                app.RaspberryMenu.Checked = 0 ;
            else
                app.LampRaspberry.Enable = true;
                app.RaspberryMenu.Checked = 1 ;
                app.mode = 2 ;
                app.ArduinoMenu.Checked = 0 ;
                app.LampArduino.Enable = 0 ; 
                app.NoneMenu.Checked = 0 ;
                app.LampTest.Enable = 0 ; 
            end
        end

        % Menu selected function: NoneMenu
        function NoneMenuSelected(app, event)
            if app.LampTest.Enable == true
                app.LampTest.Enable = false;
                app.RaspberryMenu.Checked = 0;
            else
                app.LampTest.Enable = true;
                app.NoneMenu.Checked = 1 ;
                app.mode = 3 ;
                app.ArduinoMenu.Checked = 0 ;
                app.LampArduino.Enable = 0 ; 
                app.RaspberryMenu.Checked = 0 ;
                app.LampRaspberry.Enable = 0 ; 
            end
        end

        % Callback function
        function BaudRateValueChanged(app, event)
            value = app.BaudRate.Value;
            app.baudrate=value;
        end

        % Button pushed function: SaveImagesButton
        function SaveImagesButtonPushed(app, event)
            save_data(app);
        end

        % Menu selected function: InstructionsMenu
        function InstructionsMenuSelected(app, event)
            app.DialogApp=read_me_window; %opens the read me window
        end

        % Value changed function: BaudRateDropDown
        function BaudRateDropDownValueChanged(app, event)
            value = app.BaudRateDropDown.Value;
            app.baudrate=value;
        end

        % Button pushed function: ReCenterMapButton
        function ReCenterMapButtonPushed(app, event)
            app.gx.MapCenter = [app.data(1).gps, app.data(2).gps];
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            %&& strcmp(app.ReadyLamp.Color, 'green')
            if strcmp(app.Switch.Value, 'Off') 
                cla(app.UIAxes)
                cla(app.UIAxes2)
                cla(app.UIAxes3)
                startupFcn(app);
                if strcmp(app.ReadyLamp.Color, 'green')
                       disp('pila')
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InterfaceREDv20UIFigure = uifigure('Visible', 'off');
            app.InterfaceREDv20UIFigure.Name = 'Interface RED v2.0';
            app.InputMenu = uimenu(app.InterfaceREDv20UIFigure);
            app.InputMenu.Text = 'Input';
            app.ArduinoMenu = uimenu(app.InputMenu);
            app.ArduinoMenu.MenuSelectedFcn = createCallbackFcn(app, @ArduinoMenuSelected, true);
            app.ArduinoMenu.Text = 'Arduino';
            app.RaspberryMenu = uimenu(app.InputMenu);
            app.RaspberryMenu.MenuSelectedFcn = createCallbackFcn(app, @RaspberryMenuSelected, true);
            app.RaspberryMenu.Text = 'Raspberry';
            app.NoneMenu = uimenu(app.InputMenu);
            app.NoneMenu.MenuSelectedFcn = createCallbackFcn(app, @NoneMenuSelected, true);
            app.NoneMenu.Text = 'None';
            app.ReadMeMenu = uimenu(app.InterfaceREDv20UIFigure);
            app.ReadMeMenu.Tooltip = {''};
            app.ReadMeMenu.Text = 'ReadMe';
            app.InstructionsMenu = uimenu(app.ReadMeMenu);
            app.InstructionsMenu.MenuSelectedFcn = createCallbackFcn(app, @InstructionsMenuSelected, true);
            app.InstructionsMenu.Text = 'Instructions';
            app.Mapa2DPanel = uipanel(app.InterfaceREDv20UIFigure);
            app.Mapa2DPanel.Title = 'Mapa 2D';
            app.Lamp = uilamp(app.InterfaceREDv20UIFigure);
            app.Switch = uiswitch(app.InterfaceREDv20UIFigure, 'toggle');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Image = uiimage(app.InterfaceREDv20UIFigure);
            app.Image.ImageSource = 'RED.png';
            app.Interfacev20Label = uilabel(app.InterfaceREDv20UIFigure);
            app.Interfacev20Label.Text = 'Interface v2.0';
            app.UIAxes = uiaxes(app.InterfaceREDv20UIFigure);
            title(app.UIAxes, 'Trajectory')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.InterfaceREDv20UIFigure);
            title(app.UIAxes2, 'Rocket Status')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.InterfaceREDv20UIFigure);
            title(app.UIAxes3, 'Velocity')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.ReadyLampLabel = uilabel(app.InterfaceREDv20UIFigure);
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp = uilamp(app.InterfaceREDv20UIFigure);
            app.UITable = uitable(app.InterfaceREDv20UIFigure);
            app.UITable.ColumnName = {'Latitude'; 'Longitude'; 'Altitude'; 'Tempo'};
            app.UITable.RowName = {};
            app.COMDropDownLabel = uilabel(app.InterfaceREDv20UIFigure);
            app.COMDropDownLabel.Text = 'COM';
            app.COMDropDown = uidropdown(app.InterfaceREDv20UIFigure);
            app.COMDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', ''};
            app.COMDropDown.ValueChangedFcn = createCallbackFcn(app, @COMDropDownValueChanged, true);
            app.COMDropDown.Value = '1';
            app.ImageArduino = uiimage(app.InterfaceREDv20UIFigure);
            app.ImageArduino.ImageSource = 'arduino.png';
            app.ImageRaspberry = uiimage(app.InterfaceREDv20UIFigure);
            app.ImageRaspberry.ImageSource = 'raspberry.png';
            app.ImageTest = uiimage(app.InterfaceREDv20UIFigure);
            app.ImageTest.ImageSource = 'test.png';
            app.LampArduino = uilamp(app.InterfaceREDv20UIFigure);
            app.LampArduino.Enable = 'off';
            app.LampRaspberry = uilamp(app.InterfaceREDv20UIFigure);
            app.LampRaspberry.Enable = 'off';
            app.LampTest = uilamp(app.InterfaceREDv20UIFigure);
            app.LampTest.Enable = 'off';
            app.SaveImagesButton = uibutton(app.InterfaceREDv20UIFigure, 'push');
            app.SaveImagesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImagesButtonPushed, true);
            app.SaveImagesButton.Text = 'Save Images';
            app.ResetButton = uibutton(app.InterfaceREDv20UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.BaudRateDropDownLabel = uilabel(app.InterfaceREDv20UIFigure);
            app.BaudRateDropDownLabel.Text = 'BaudRate';
            app.BaudRateDropDown = uidropdown(app.InterfaceREDv20UIFigure);
            app.BaudRateDropDown.Items = {'300', '600', '1200', '2400', '4800', '9600', '14400', '19200', '38400', '57600', '115200'};
            app.BaudRateDropDown.ValueChangedFcn = createCallbackFcn(app, @BaudRateDropDownValueChanged, true);
            app.BaudRateDropDown.Value = '9600';
            app.ReCenterMapButton = uibutton(app.InterfaceREDv20UIFigure, 'push');
            app.ReCenterMapButton.ButtonPushedFcn = createCallbackFcn(app, @ReCenterMapButtonPushed, true);
            app.ReCenterMapButton.Text = 'Re-Center Map';
            % Show the figure after all components are created
            app.InterfaceREDv20UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interface_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InterfaceREDv20UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InterfaceREDv20UIFigure)
        end
    end
end